classdef String_vibrations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        speed                    matlab.ui.control.NumericEditField
        left_border              matlab.ui.control.NumericEditField
        right_border             matlab.ui.control.NumericEditField
        time_begin               matlab.ui.control.NumericEditField
        time_end                 matlab.ui.control.NumericEditField
        discretization           matlab.ui.control.NumericEditField
        initial_position         matlab.ui.control.EditField
        initial_speed            matlab.ui.control.EditField
        Label_4                  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        EditField_2Label         matlab.ui.control.Label
        uxEditFieldLabel         matlab.ui.control.Label
        EditField_5Label         matlab.ui.control.Label
        EditField_4Label         matlab.ui.control.Label
        left_boundary_function   matlab.ui.control.EditField
        left_boundary_type       matlab.ui.container.ButtonGroup
        left_boundary_position   matlab.ui.control.RadioButton
        left_boundary_speed      matlab.ui.control.RadioButton
        right_boundary_type      matlab.ui.container.ButtonGroup
        right_boundary_position  matlab.ui.control.RadioButton
        right_boundary_speed     matlab.ui.control.RadioButton
        right_boundary_function  matlab.ui.control.EditField
        EditField_8Label         matlab.ui.control.Label
        EditField_7Label         matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        button_calculate         matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        axes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: button_calculate
        function button_calculateButtonPushed(app, event)
            %% параметры
            x_lim = [app.left_border.Value app.right_border.Value];  % границы пространства
            t_lim = [app.time_begin.Value app.time_end.Value]; % границы по времени
            c = app.speed.Value;          % скорость звука
            dx = app.discretization.Value;     % пространственный шаг
            
            % внешняя сила
            external_force = @(x, t) 0;
            % начальное значение
            init_value = str2func("@(x) " + app.initial_position.Value);
            % начальная скорость
            init_speed = str2func("@(x) " + app.initial_speed.Value);
            
            % граничные условия
            left_constrain = str2func("@(x) " + app.left_boundary_function.Value);
            right_constrain = str2func("@(x) " + app.right_boundary_function.Value);
            
            %% расчет переменных
            dt = dx / c;                % шаг по времени
            x = x_lim(1):dx:x_lim(2);   % вектор значений коордитнаты
            t = t_lim(1):dt:t_lim(2);   % вектор значений времени
            
            % создание массива для решения
            string = zeros(length(t), length(x));
            
            % начальные условия
            string(1, :) = init_value(x);
            string(2, :) = string(1, :) + dt * init_speed(x);
            
            % граничные условия
            string(:, 1) = left_constrain(t);
            string(:, end) = right_constrain(t);
            
            % график начальных условий
            % plot(x, String(1, :), "LineWidth", 2)
            
            %% расчёт в цикле
            tic
            for i = 3:size(string, 1)
                for j = 2:(size(string, 2) - 1)
                    string(i, j) = string(i - 1, j - 1) + string(i - 1, j + 1) - ...
                        string(i - 2, j) + dt^2 * external_force(x(j), t(i));
                end
            end
            toc
            
            %% график всего
            [X, T] = meshgrid(x, t);
            ax = app.axes;
            contour(ax, X, T, string, "Fill", "on", "LineColor", "k", "LineWidth", 1);
            xlabel(ax, "x, м");
            ylabel(ax, "t, c");
            c = colorbar(ax);
            c.Label.String = "y, м";
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {617, 617};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {368, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.button_calculate = uibutton(app.LeftPanel, 'push');
            app.button_calculate.ButtonPushedFcn = createCallbackFcn(app, @button_calculateButtonPushed, true);
            app.button_calculate.Text = 'Расчитать';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'Левая граница';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = 'Правая граница';
            app.EditField_7Label = uilabel(app.LeftPanel);
            app.EditField_7Label.Text = 'Граничное условие слева';
            app.EditField_8Label = uilabel(app.LeftPanel);
            app.EditField_8Label.Text = 'Граничное условие справа';
            app.right_boundary_function = uieditfield(app.LeftPanel, 'text');
            app.right_boundary_function.Value = '0';
            app.right_boundary_type = uibuttongroup(app.LeftPanel);
            app.right_boundary_type.Title = 'Тип граничного условия справа';
            app.right_boundary_speed = uiradiobutton(app.right_boundary_type);
            app.right_boundary_speed.Text = 'Скорость';
            app.right_boundary_position = uiradiobutton(app.right_boundary_type);
            app.right_boundary_position.Text = 'Положение';
            app.right_boundary_position.Value = true;
            app.left_boundary_type = uibuttongroup(app.LeftPanel);
            app.left_boundary_type.Title = 'Тип граничного условия слева';
            app.left_boundary_speed = uiradiobutton(app.left_boundary_type);
            app.left_boundary_speed.Text = 'Скорость';
            app.left_boundary_position = uiradiobutton(app.left_boundary_type);
            app.left_boundary_position.Text = 'Положение';
            app.left_boundary_position.Value = true;
            app.left_boundary_function = uieditfield(app.LeftPanel, 'text');
            app.left_boundary_function.Value = '0';
            app.EditField_4Label = uilabel(app.LeftPanel);
            app.EditField_4Label.Text = 'Начальный момент времени';
            app.EditField_5Label = uilabel(app.LeftPanel);
            app.EditField_5Label.Text = 'Конечный момент времени';
            app.uxEditFieldLabel = uilabel(app.LeftPanel);
            app.uxEditFieldLabel.Text = 'Начальное положение u(x)';
            app.EditField_2Label = uilabel(app.LeftPanel);
            app.EditField_2Label.Text = 'Скорость звука';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = 'Шаг дискретизации по длине';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = 'Начальная скорость u''(x)';
            app.initial_speed = uieditfield(app.LeftPanel, 'text');
            app.initial_speed.Value = '0';
            app.initial_position = uieditfield(app.LeftPanel, 'text');
            app.initial_position.Value = 'sin(pi*x)';
            app.discretization = uieditfield(app.LeftPanel, 'numeric');
            app.discretization.Value = 0.001;
            app.time_end = uieditfield(app.LeftPanel, 'numeric');
            app.time_end.Value = 1;
            app.time_begin = uieditfield(app.LeftPanel, 'numeric');
            app.right_border = uieditfield(app.LeftPanel, 'numeric');
            app.right_border.Value = 1;
            app.left_border = uieditfield(app.LeftPanel, 'numeric');
            app.speed = uieditfield(app.LeftPanel, 'numeric');
            app.speed.Limits = [0 Inf];
            app.speed.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.axes = uiaxes(app.RightPanel);
            title(app.axes, 'Title')
            xlabel(app.axes, 'X')
            ylabel(app.axes, 'Y')
            zlabel(app.axes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = String_vibrations

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end